classdef TFM_ArgoJ8_Dashboard < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        Visualizacion2D3DPanel          matlab.ui.container.Panel
        TabGroup                        matlab.ui.container.TabGroup
        Relieve3DTab                    matlab.ui.container.Tab
        GridLayout4                     matlab.ui.container.GridLayout
        RelieveMapa                     matlab.ui.control.UIAxes
        Planta2DTab                     matlab.ui.container.Tab
        GridLayout2                     matlab.ui.container.GridLayout
        LienzoMapa                      matlab.ui.control.UIAxes
        ZoomRelieveTab                  matlab.ui.container.Tab
        GridLayout3                     matlab.ui.container.GridLayout
        LienzoZoom3D                    matlab.ui.control.UIAxes
        CamaraBordoFPVTab               matlab.ui.container.Tab
        GridLayout5                     matlab.ui.container.GridLayout
        OrientacinLabel                 matlab.ui.control.Label
        OrientacionGauge                matlab.ui.control.Gauge
        CabeceoGaugeLabel               matlab.ui.control.Label
        InclinometroGauge               matlab.ui.control.SemicircularGauge
        VelocidadkmhLabel               matlab.ui.control.Label
        VelocimetroGauge                matlab.ui.control.SemicircularGauge
        LienzoFPV                       matlab.ui.control.UIAxes
        PaneldeControl                  matlab.ui.container.Panel
        Label_4                         matlab.ui.control.Label
        Label_3                         matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        CargarMapaButton                matlab.ui.control.Button
        SeleccionarMapaButton           matlab.ui.control.Button
        ControladorLocalDropDown        matlab.ui.control.DropDown
        EvasinDinmicaLabel              matlab.ui.control.Label
        ConsoladelSistemaTextArea       matlab.ui.control.TextArea
        ConsoladelSistemaTextAreaLabel  matlab.ui.control.Label
        BotonExportar                   matlab.ui.control.Button
        BotonEStop                      matlab.ui.control.Button
        ReiniciarButton                 matlab.ui.control.Button
        BotonSeguir                     matlab.ui.control.Button
        BotonLimpiarObstaculos          matlab.ui.control.Button
        BotonAnadirObstaculo            matlab.ui.control.Button
        FormaObstaculoDropDown          matlab.ui.control.DropDown
        FormadelObstculoDropDownLabel   matlab.ui.control.Label
        BotonCalcular                   matlab.ui.control.Button
        PlanificadorGlobalDropDown      matlab.ui.control.DropDown
        PlanificadorGlobalDropDownLabel  matlab.ui.control.Label
        BotonPosicionar                 matlab.ui.control.Button
        ModelodelRobotDropDown          matlab.ui.control.DropDown
        ModelodelRobotDropDownLabel     matlab.ui.control.Label
        SeleccionarMetaButton           matlab.ui.control.Button
        SeleccionarInicioButton         matlab.ui.control.Button
        PosicionesInicialyFinalLabel    matlab.ui.control.Label
    end


    properties (Access = private)
        % --- Variables de Estado ---
        PoseInicio
        PoseMeta
        PuntoInicioXY
        PuntoMetaXY
        RutaGlobal
        DatosMision
        FlagEStop

        ArchivoMapaSeleccionado

        % --- Mapas y Gráficos ---
        MapaOcupacion
        MapaOcupacionOriginal
        RejillaVisual
        ImagenTextura
        MallaX
        MallaY

        % --- CAJA NEGRA (DATA LOGGER MULTIMISIÓN) ---
        ContadorMisiones
        RegistroTelemetriaGlobal
        RegistroMisionesGlobal
        RegistroObstaculos
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIFigure.WindowState = 'maximized';
            app.ConsoladelSistemaTextArea.Value = {'Iniciando Sistema de Simulación...'; 'Esperando selección de archivo DEM (.mat).'};
            drawnow;

            app.ArchivoMapaSeleccionado = '';

            % Deshabilitar controles hasta cargar el entorno
            app.SeleccionarInicioButton.Enable = 'off';
            app.SeleccionarMetaButton.Enable = 'off';
            app.BotonPosicionar.Enable = 'off';
            app.BotonCalcular.Enable = 'off';
            app.BotonSeguir.Enable = 'off';

            ruta_app = fileparts(mfilename('fullpath'));
            addpath(genpath(fullfile(ruta_app, '..')));
        end

        % Button pushed function: BotonPosicionar
        function BotonPosicionarButtonPushed(app, event)
            if isempty(app.PuntoInicioXY) || isempty(app.PoseInicio)
                uialert(app.UIFigure, 'Defina un punto de inicio en el mapa 2D.', 'Aviso');
                return;
            end

            x_act = app.PoseInicio(1);
            y_act = app.PoseInicio(2);
            th_act = app.PoseInicio(3);
            modelo_robot = app.ModelodelRobotDropDown.Value;

            app.RutaGlobal = [];
            app.BotonSeguir.Enable = 'off';

            % Limpieza de trayectorias anteriores
            delete(findobj(app.LienzoMapa, 'Tag', 'RutaTrazada2D')); delete(findobj(app.LienzoMapa, 'Tag', 'Meta2D'));
            delete(findobj(app.RelieveMapa, 'Tag', 'RutaTrazada3D')); delete(findobj(app.RelieveMapa, 'Tag', 'Meta3D'));
            delete(findobj(app.LienzoZoom3D, 'Tag', 'RutaTrazadaZoom')); delete(findobj(app.LienzoZoom3D, 'Tag', 'MetaZoom'));
            delete(findobj(app.LienzoFPV, 'Tag', 'RutaTrazadaFPV')); delete(findobj(app.LienzoFPV, 'Tag', 'MetaFPV'));

            app.ConsoladelSistemaTextArea.Value = [app.ConsoladelSistemaTextArea.Value; ...
                {sprintf('Generando modelo URDF en X:%.1f, Y:%.1f, Yaw:%.1fº...', x_act, y_act, rad2deg(th_act))}];
            scroll(app.ConsoladelSistemaTextArea, 'bottom');
            drawnow;

            hold(app.LienzoMapa, 'on');
            delete(findobj(app.LienzoMapa, 'Tag', 'Robot2D'));
            delete(findobj(app.LienzoMapa, 'Tag', 'Robot2D_Temp'));
            delete(findobj(app.LienzoMapa, 'Tag', 'Flecha2D'));

            plot(app.LienzoMapa, x_act, y_act, 'gs', 'MarkerSize', 10, 'MarkerFaceColor', 'g', 'Tag', 'Robot2D');
            quiver(app.LienzoMapa, x_act, y_act, cos(th_act)*15, sin(th_act)*15, ...
                'Color', 'm', 'LineWidth', 2.5, 'MaxHeadSize', 2, 'AutoScale', 'off', 'Tag', 'Flecha2D');
            hold(app.LienzoMapa, 'off');

            % Configuración geométrica según modelo
            if strcmp(modelo_robot, 'Argo J8')
                ruta_app = fileparts(which('TFM_ArgoJ8_Dashboard.mlapp'));
                if isempty(ruta_app)
                    ruta_app = pwd;
                end
                ruta_urdf = fullfile(ruta_app, '..', 'models', 'Argo_J8', 'URDF', 'argo_j8_v5.urdf');
                L = 1.00; W = 0.60; offset_z = 0.58;
                T_compensacion = eye(4);
                cam_height = 0.8; offset_fwd = 2.0;
            else
                % Ruta relativa universal para el Rambler
                ruta_app = fileparts(which('TFM_ArgoJ8_Dashboard.mlapp'));
                if isempty(ruta_app)
                    ruta_app = pwd;
                end
                ruta_urdf = fullfile(ruta_app, '..', 'models', 'rambler_urdf', 'rambler copy.urdf');
                L = 0.80; W = 0.60; offset_z = 0.66;
                T_trans = eye(4); T_trans(1:3, 4) = [15.504313; 8.557643; -3.314363];
                T_rot = eye(4); ang = -pi/2;
                T_rot(1:3, 1:3) = [cos(ang), -sin(ang), 0; sin(ang), cos(ang), 0; 0, 0, 1];
                T_compensacion = T_rot * T_trans;
                cam_height = 0.7; offset_fwd = 1.2;
            end

            try
                robot = importrobot(ruta_urdf, 'DataFormat', 'column');
                config_visual = homeConfiguration(robot);
            catch
                app.ConsoladelSistemaTextArea.Value = [app.ConsoladelSistemaTextArea.Value; {'[ERROR] URDF no encontrado.'}];
                return;
            end

            % Cálculo de la matriz de transformación homogénea sobre terreno
            x_FL = x_act + L*cos(th_act) - W*sin(th_act); y_FL = y_act + L*sin(th_act) + W*cos(th_act);
            x_FR = x_act + L*cos(th_act) + W*sin(th_act); y_FR = y_act + L*sin(th_act) - W*cos(th_act);
            x_BL = x_act - L*cos(th_act) - W*sin(th_act); y_BL = y_act - L*sin(th_act) + W*cos(th_act);
            x_BR = x_act - L*cos(th_act) + W*sin(th_act); y_BR = y_act - L*sin(th_act) - W*cos(th_act);

            z_FL = interp2(app.MallaX, app.MallaY, app.RejillaVisual, x_FL, y_FL, 'linear');
            z_FR = interp2(app.MallaX, app.MallaY, app.RejillaVisual, x_FR, y_FR, 'linear');
            z_BL = interp2(app.MallaX, app.MallaY, app.RejillaVisual, x_BL, y_BL, 'linear');
            z_BR = interp2(app.MallaX, app.MallaY, app.RejillaVisual, x_BR, y_BR, 'linear');

            P_FL = [x_FL; y_FL; z_FL]; P_FR = [x_FR; y_FR; z_FR];
            P_BL = [x_BL; y_BL; z_BL]; P_BR = [x_BR; y_BR; z_BR];

            V_fwd = (P_FL + P_FR)/2 - (P_BL + P_BR)/2; V_fwd = V_fwd / norm(V_fwd);
            V_left_temp = (P_FL + P_BL)/2 - (P_FR + P_BR)/2;
            V_up = cross(V_fwd, V_left_temp); V_up = V_up / norm(V_up);
            V_left = cross(V_up, V_fwd); V_left = V_left / norm(V_left);

            tform = eye(4); tform(1:3, 1:3) = [V_fwd, V_left, V_up];
            altura_base = mean([z_FL, z_FR, z_BL, z_BR]);
            tform(1:3, 4) = [x_act; y_act; altura_base + offset_z];
            tform_corregida = tform * T_compensacion;

            % Instanciación de modelos en Axes 3D
            delete(findobj(app.RelieveMapa, 'Tag', 'RobotURDF_Global'));
            h_trans3D = hgtransform('Parent', app.RelieveMapa, 'Matrix', tform_corregida, 'Tag', 'RobotURDF_Global');
            f_temp = figure('Visible', 'off'); ax_temp = axes('Parent', f_temp);
            show(robot, config_visual, 'Parent', ax_temp, 'Visuals', 'on', 'Collisions', 'off');
            piezas_robot = allchild(ax_temp);
            set(piezas_robot, 'Parent', h_trans3D);
            if strcmp(modelo_robot, 'Rambler'), set(findobj(h_trans3D, 'Type', 'Line'), 'Visible', 'off'); end
            close(f_temp);

            % Vista Zoom
            if isempty(findobj(app.LienzoZoom3D, 'Type', 'Surface'))
                superficie = findobj(app.RelieveMapa, 'Type', 'Surface');
                copyobj(superficie, app.LienzoZoom3D);
                camlight(app.LienzoZoom3D, 'headlight'); lighting(app.LienzoZoom3D, 'gouraud');
                daspect(app.LienzoZoom3D, [1 1 1]); grid(app.LienzoZoom3D, 'on');
            end
            hold(app.LienzoZoom3D, 'on');
            delete(findobj(app.LienzoZoom3D, 'Tag', 'RobotURDF_Zoom'));
            h_transZoom = hgtransform('Parent', app.LienzoZoom3D, 'Matrix', tform_corregida, 'Tag', 'RobotURDF_Zoom');
            copyobj(piezas_robot, h_transZoom);
            if strcmp(modelo_robot, 'Rambler'), set(findobj(h_transZoom, 'Type', 'Line'), 'Visible', 'off'); end
            view(app.LienzoZoom3D, -35, 15);
            xlim(app.LienzoZoom3D, [x_act - 20, x_act + 20]); ylim(app.LienzoZoom3D, [y_act - 20, y_act + 20]);
            zlim(app.LienzoZoom3D, [altura_base - 10, altura_base + 15]);
            hold(app.LienzoZoom3D, 'off');

            % Vista FPV (Cámara a bordo)
            if isempty(findobj(app.LienzoFPV, 'Type', 'Surface'))
                superficie_fpv = findobj(app.RelieveMapa, 'Type', 'Surface');
                copyobj(superficie_fpv, app.LienzoFPV);
                camlight(app.LienzoFPV, 'headlight'); lighting(app.LienzoFPV, 'gouraud');
                daspect(app.LienzoFPV, [1 1 1]); axis(app.LienzoFPV, 'off'); camproj(app.LienzoFPV, 'perspective');
            end
            hold(app.LienzoFPV, 'on');
            delete(findobj(app.LienzoFPV, 'Tag', 'RobotURDF_FPV'));
            h_transFPV = hgtransform('Parent', app.LienzoFPV, 'Matrix', tform_corregida, 'Tag', 'RobotURDF_FPV');
            copyobj(piezas_robot, h_transFPV);
            if strcmp(modelo_robot, 'Rambler'), set(findobj(h_transFPV, 'Type', 'Line'), 'Visible', 'off'); end

            pos_ojo = [x_act + offset_fwd*V_fwd(1), y_act + offset_fwd*V_fwd(2), altura_base + cam_height];
            pos_objetivo = [x_act + 20*V_fwd(1), y_act + 20*V_fwd(2), altura_base + cam_height + 5];
            campos(app.LienzoFPV, pos_ojo); camtarget(app.LienzoFPV, pos_objetivo);
            camup(app.LienzoFPV, [0 0 1]); camva(app.LienzoFPV, 90);
            hold(app.LienzoFPV, 'off');

            app.BotonCalcular.Enable = 'on';
            app.ConsoladelSistemaTextArea.Value = [app.ConsoladelSistemaTextArea.Value; {'Modelo cinemático instanciado correctamente.'}];
            scroll(app.ConsoladelSistemaTextArea, 'bottom');

            if isprop(app, 'OrientacionGauge') && ~isempty(app.OrientacionGauge)
                app.OrientacionGauge.Value = rad2deg(th_act);
            end
        end

        % Button pushed function: BotonCalcular
        function BotonCalcularButtonPushed(app, event)
            if isempty(app.PuntoMetaXY) || isempty(app.PoseMeta)
                uialert(app.UIFigure, 'Defina un vector de meta en el mapa primero.', 'Aviso');
                return;
            end

            planificador = app.PlanificadorGlobalDropDown.Value;
            modelo_robot = app.ModelodelRobotDropDown.Value;
            start_pose = app.PoseInicio;
            goal_pose = [app.PoseMeta(1), app.PoseMeta(2), app.PoseMeta(3)];

            app.ConsoladelSistemaTextArea.Value = [app.ConsoladelSistemaTextArea.Value; ...
                {''}; ... % Línea en blanco separadora
                {'[PLANIFICADOR GLOBAL]'}; ...
                {sprintf('> Calculando grafo %s hacia meta (X:%.1f, Y:%.1f)...', planificador, goal_pose(1), goal_pose(2))}];
            scroll(app.ConsoladelSistemaTextArea, 'bottom');
            drawnow;

            % Restricciones vehiculares
            if strcmp(modelo_robot, 'Argo J8')
                radio_giro_minimo = 2.0;
                dist_prm = 60; dist_rrt = 35; val_dist = 0.5;
            else
                radio_giro_minimo = 1.5;
                dist_prm = 40; dist_rrt = 20; val_dist = 0.2;
            end

            espacio_estado = stateSpaceSE2;
            espacio_estado.StateBounds = [app.MapaOcupacion.XWorldLimits; app.MapaOcupacion.YWorldLimits; [-pi pi]];
            validador = validatorOccupancyMap(espacio_estado);
            validador.Map = app.MapaOcupacion;
            validador.ValidationDistance = val_dist;

            ruta_ideal = []; prm_obj = []; arbol_RRT = []; ruta_HA_states = [];

            try
                % Resolución de rutas
                switch planificador
                    case 'PRM'
                        prm = mobileRobotPRM(app.MapaOcupacion, 500);
                        prm.ConnectionDistance = dist_prm;
                        ruta_ideal = findpath(prm, start_pose(1:2), goal_pose(1:2));
                        prm_obj = prm;
                    case 'RRT*'
                        plannerRRT = plannerRRTStar(espacio_estado, validador);
                        plannerRRT.MaxConnectionDistance = dist_rrt; plannerRRT.MaxIterations = 4000; rng(1);
                        [ruta_obj, arbol] = plan(plannerRRT, start_pose, goal_pose);
                        if ruta_obj.NumStates > 0
                            ruta_ideal = ruta_obj.States(:, 1:2); arbol_RRT = arbol;
                        end
                    case 'A Grid*'
                        start_grid = world2grid(app.MapaOcupacion, start_pose(1:2)); goal_grid  = world2grid(app.MapaOcupacion, goal_pose(1:2));
                        mapa_astar = copy(app.MapaOcupacion);
                        setOccupancy(mapa_astar, start_grid, 0, 'grid'); setOccupancy(mapa_astar, goal_grid, 0, 'grid');
                        plannerAStar = plannerAStarGrid(mapa_astar); ruta_grid = plan(plannerAStar, start_grid, goal_grid);
                        if ~isempty(ruta_grid), ruta_ideal = grid2world(app.MapaOcupacion, ruta_grid); end
                    case 'Hybrid A*'
                        long_primitiva = radio_giro_minimo * 1.5;
                        plannerHA = plannerHybridAStar(validador, 'MinTurningRadius', radio_giro_minimo, 'MotionPrimitiveLength', long_primitiva);
                        ruta_obj = plan(plannerHA, start_pose, goal_pose);
                        if ruta_obj.NumStates > 0
                            ruta_ideal = ruta_obj.States(:, 1:2); ruta_HA_states = ruta_obj.States;
                        end
                end

                if isempty(ruta_ideal)
                    app.ConsoladelSistemaTextArea.Value = [app.ConsoladelSistemaTextArea.Value; {'[AVISO] No se encontró ruta factible.'}];
                    scroll(app.ConsoladelSistemaTextArea, 'bottom');
                    return;
                end
                app.RutaGlobal = ruta_ideal;

                % Trazado de trayectorias
                delete(findobj(app.LienzoMapa, 'Tag', 'RutaTrazada2D')); delete(findobj(app.LienzoMapa, 'Tag', 'Meta2D'));
                delete(findobj(app.LienzoMapa, 'Tag', 'Meta2D_Temp'));
                delete(findobj(app.RelieveMapa, 'Tag', 'RutaTrazada3D')); delete(findobj(app.RelieveMapa, 'Tag', 'Meta3D'));
                delete(findobj(app.LienzoZoom3D, 'Tag', 'RutaTrazadaZoom')); delete(findobj(app.LienzoZoom3D, 'Tag', 'MetaZoom'));
                delete(findobj(app.LienzoFPV, 'Tag', 'RutaTrazadaFPV')); delete(findobj(app.LienzoFPV, 'Tag', 'MetaFPV'));

                hold(app.LienzoMapa, 'on');
                plot(app.LienzoMapa, goal_pose(1), goal_pose(2), 'bo', 'MarkerSize', 10, 'MarkerFaceColor', 'b', 'Tag', 'Meta2D');
                plot(app.LienzoMapa, ruta_ideal(:,1), ruta_ideal(:,2), 'r--', 'LineWidth', 2.5, 'Tag', 'RutaTrazada2D');
                hold(app.LienzoMapa, 'off');

                z_ruta = interp2(app.MallaX, app.MallaY, app.RejillaVisual, ruta_ideal(:,1), ruta_ideal(:,2), 'linear');
                z_meta = interp2(app.MallaX, app.MallaY, app.RejillaVisual, goal_pose(1), goal_pose(2), 'linear');
                offset_z = 1.0;

                hold(app.RelieveMapa, 'on');
                plot3(app.RelieveMapa, goal_pose(1), goal_pose(2), z_meta + offset_z, 'bo', 'MarkerSize', 10, 'MarkerFaceColor', 'b', 'Tag', 'Meta3D');
                plot3(app.RelieveMapa, ruta_ideal(:,1), ruta_ideal(:,2), z_ruta + offset_z, 'r--', 'LineWidth', 2.5, 'Tag', 'RutaTrazada3D');
                hold(app.RelieveMapa, 'off');

                hold(app.LienzoZoom3D, 'on');
                plot3(app.LienzoZoom3D, goal_pose(1), goal_pose(2), z_meta + offset_z, 'bo', 'MarkerSize', 10, 'MarkerFaceColor', 'b', 'Tag', 'MetaZoom');
                plot3(app.LienzoZoom3D, ruta_ideal(:,1), ruta_ideal(:,2), z_ruta + offset_z, 'r--', 'LineWidth', 2.5, 'Tag', 'RutaTrazadaZoom');
                hold(app.LienzoZoom3D, 'off');

                hold(app.LienzoFPV, 'on');
                plot3(app.LienzoFPV, goal_pose(1), goal_pose(2), z_meta + offset_z, 'bo', 'MarkerSize', 10, 'MarkerFaceColor', 'b', 'Tag', 'MetaFPV');
                plot3(app.LienzoFPV, ruta_ideal(:,1), ruta_ideal(:,2), z_ruta + offset_z, 'r--', 'LineWidth', 2.5, 'Tag', 'RutaTrazadaFPV');
                hold(app.LienzoFPV, 'off');

                % Figuras de validación
                close(findobj('Type', 'Figure', 'Tag', 'FigValidacionInflado'));
                close(findobj('Type', 'Figure', 'Tag', 'FigValidacionRugosidad'));
                close(findobj('Type', 'Figure', 'Tag', 'FigValidacionInterna'));

                figure('Name', sprintf('Mapa de Seguridad (%s)', planificador), 'Tag', 'FigValidacionInflado', 'NumberTitle', 'off');
                show(validador.Map); hold on;
                plot(start_pose(1), start_pose(2), 'gs', 'MarkerSize', 10, 'MarkerFaceColor', 'g');
                plot(goal_pose(1), goal_pose(2), 'bo', 'MarkerSize', 10, 'MarkerFaceColor', 'b');
                plot(ruta_ideal(:,1), ruta_ideal(:,2), 'r-', 'LineWidth', 2.5);
                title('Trazado sobre Costmap Inflado');

                figure('Name', 'Evaluación de Rugosidad', 'Tag', 'FigValidacionRugosidad', 'NumberTitle', 'off');
                rugosidad = stdfilt(app.RejillaVisual, true(5));
                imagesc('XData', app.MallaX(1,:), 'YData', app.MallaY(:,1), 'CData', rugosidad);
                set(gca, 'YDir', 'normal'); colormap(gca, 'hot'); colorbar; hold on;
                plot(start_pose(1), start_pose(2), 'gs', 'MarkerSize', 10, 'MarkerFaceColor', 'g');
                plot(goal_pose(1), goal_pose(2), 'bo', 'MarkerSize', 10, 'MarkerFaceColor', 'b');
                plot(ruta_ideal(:,1), ruta_ideal(:,2), 'c-', 'LineWidth', 2.5);
                title('Ruta frente al Índice de Rugosidad Superficial');

                figure('Name', 'Estructura Interna del Planificador', 'Tag', 'FigValidacionInterna', 'NumberTitle', 'off');
                switch planificador
                    case 'PRM', show(prm_obj); hold on; title('Grafo de Conectividad (PRM)');
                    case 'RRT*'
                        show(app.MapaOcupacion); hold on;
                        if ~isempty(arbol_RRT), plot(arbol_RRT.TreeData(:,1), arbol_RRT.TreeData(:,2), '.-', 'Color', [0.7 0.7 0.7]); end
                        title('Árbol de Expansión (RRT*)');
                    case 'A Grid*', show(app.MapaOcupacion); hold on; title('Búsqueda Discreta (A*)');
                    case 'Hybrid A*'
                        show(app.MapaOcupacion); hold on;
                        if ~isempty(ruta_HA_states), quiver(ruta_HA_states(1:5:end,1), ruta_HA_states(1:5:end,2), cos(ruta_HA_states(1:5:end,3)), sin(ruta_HA_states(1:5:end,3)), 0.5, 'r', 'LineWidth', 1.5); end
                        title('Primitivas de Movimiento (Hybrid A*)');
                end
                plot(start_pose(1), start_pose(2), 'gs', 'MarkerSize', 10, 'MarkerFaceColor', 'g');
                plot(goal_pose(1), goal_pose(2), 'bo', 'MarkerSize', 10, 'MarkerFaceColor', 'b');
                plot(ruta_ideal(:,1), ruta_ideal(:,2), 'm-', 'LineWidth', 3);

                app.BotonSeguir.Enable = 'on';
                app.ConsoladelSistemaTextArea.Value = [app.ConsoladelSistemaTextArea.Value; {'Ruta validada con éxito.'}];
                scroll(app.ConsoladelSistemaTextArea, 'bottom');

            catch ME
                app.ConsoladelSistemaTextArea.Value = [app.ConsoladelSistemaTextArea.Value; {'[ERROR] Fallo en la resolución de ruta:'}; {ME.message}];
                scroll(app.ConsoladelSistemaTextArea, 'bottom');
            end
        end

        % Button pushed function: BotonSeguir
        function BotonSeguirButtonPushed(app, event)
            app.FlagEStop = false;
            app.DatosMision = [];
            chocado = false;
            acorralado = false;
            tipo_choque = '';

            ruta_ideal = app.RutaGlobal;
            pose_actual = reshape(app.PoseInicio, 1, 3);
            x_meta = app.PoseMeta(1);
            y_meta = app.PoseMeta(2);
            modelo_robot = app.ModelodelRobotDropDown.Value;
            tipo_controlador = app.ControladorLocalDropDown.Value;

            app.ConsoladelSistemaTextArea.Value = [app.ConsoladelSistemaTextArea.Value; ...
                {''}; ...
                {'[NAVEGACIÓN]'}; ...
                {['> Ejecutando control local: ', tipo_controlador]}];
            scroll(app.ConsoladelSistemaTextArea, 'bottom');
            drawnow;

            % Definición de restricciones vehiculares
            v_max = 5.55;
            if strcmp(modelo_robot, 'Argo J8')
                L = 1.00; W = 0.60; offset_z = 0.58; T_compensacion = eye(4);
                cam_height = 0.8; offset_fwd = 2.0; omega_max = 2.22;
                radio_robot = 2.0; dist_lookahead = 6.0;
            else
                L = 0.80; W = 0.60; offset_z = 0.66;
                T_trans = eye(4); T_trans(1:3, 4) = [15.504313; 8.557643; -3.314363];
                T_rot = eye(4); ang = -pi/2; T_rot(1:3, 1:3) = [cos(ang), -sin(ang), 0; sin(ang), cos(ang), 0; 0, 0, 1];
                T_compensacion = T_rot * T_trans;
                cam_height = 0.7; offset_fwd = 1.2; omega_max = 2.80;
                radio_robot = 1.2; dist_lookahead = 5.0;
            end

            % Instanciación de controladores
            controladorPP = controllerPurePursuit('Waypoints', ruta_ideal, 'LookaheadDistance', dist_lookahead);
            controladorPP.DesiredLinearVelocity = v_max;
            controladorPP.MaxAngularVelocity = omega_max;
            sensorLidar = rangeSensor('Range', [0.5, 15], 'HorizontalAngle', [-pi/2, pi/2], 'HorizontalAngleResolution', deg2rad(2));
            vfh = controllerVFH('DistanceLimits', [0.1, 15], 'RobotRadius', 1.0, 'SafetyDistance', 0.2, 'MinTurningRadius', v_max/omega_max);

            % Vectores de memoria de estados
            historial = pose_actual;
            z_ini = interp2(app.MallaX, app.MallaY, app.RejillaVisual, pose_actual(1), pose_actual(2), 'linear');
            z_historial = z_ini + 0.2;
            v_historial = 0; pitch_historial = 0;

            dt = 0.1; tiempo_max = 600; long_flecha = 15;
            limX = [app.MapaOcupacion.XWorldLimits(1)+1.0, app.MapaOcupacion.XWorldLimits(2)-1.0];
            limY = [app.MapaOcupacion.YWorldLimits(1)+1.0, app.MapaOcupacion.YWorldLimits(2)-1.0];

            % Inicialización gráfica del bucle
            h_robot2D = findobj(app.LienzoMapa, 'Tag', 'Robot2D'); h_flecha2D = findobj(app.LienzoMapa, 'Tag', 'Flecha2D');
            h_trans3D = findobj(app.RelieveMapa, 'Tag', 'RobotURDF_Global'); h_transZoom = findobj(app.LienzoZoom3D, 'Tag', 'RobotURDF_Zoom'); h_transFPV = findobj(app.LienzoFPV, 'Tag', 'RobotURDF_FPV');
            delete(findobj(app.LienzoMapa, 'Tag', 'Estela2D')); delete(findobj(app.RelieveMapa, 'Tag', 'Estela3D')); delete(findobj(app.LienzoZoom3D, 'Tag', 'EstelaZoom'));

            hold(app.LienzoMapa, 'on'); h_tray2D = plot(app.LienzoMapa, pose_actual(1), pose_actual(2), 'k-', 'LineWidth', 2.5, 'Tag', 'Estela2D'); hold(app.LienzoMapa, 'off');
            hold(app.RelieveMapa, 'on'); h_tray3D = plot3(app.RelieveMapa, pose_actual(1), pose_actual(2), z_ini + 0.2, 'k-', 'LineWidth', 3, 'Tag', 'Estela3D'); hold(app.RelieveMapa, 'off');
            hold(app.LienzoZoom3D, 'on'); h_trayZoom = plot3(app.LienzoZoom3D, pose_actual(1), pose_actual(2), z_ini + 0.2, 'k-', 'LineWidth', 3, 'Tag', 'EstelaZoom'); hold(app.LienzoZoom3D, 'off');

            % ==============================================================
            % INTEGRACIÓN CONTINUA (Bucle de Control)
            % ==============================================================
            for paso = 1:(tiempo_max/dt)
                if app.FlagEStop, break; end

                pose_actual = reshape(pose_actual, 1, 3);
                dist_a_meta = norm([x_meta, y_meta] - pose_actual(1:2));

                % Ajuste adaptativo del lookahead distance
                if dist_a_meta < 15.0, controladorPP.LookaheadDistance = max(1.5, dist_lookahead * (dist_a_meta / 15.0));
                else, controladorPP.LookaheadDistance = dist_lookahead; end

                [v_cmd_pp, omega_cmd_pp, target_pt] = controladorPP(pose_actual);

                if isnan(v_cmd_pp) || isnan(omega_cmd_pp), v_cmd_pp = 0; omega_cmd_pp = 0; end

                % Perfil de velocidad basado en la curvatura
                velocidad_dinamica = v_cmd_pp * exp(-1.8 * abs(omega_cmd_pp));
                if dist_a_meta < 15.0, velocidad_dinamica = velocidad_dinamica * max(0.05, (dist_a_meta / 15.0)); end
                if dist_a_meta < 3.0, velocidad_dinamica = max(0.2, velocidad_dinamica);
                else, velocidad_dinamica = max(1.0, velocidad_dinamica); end

                angulo_objetivo = atan2(target_pt(2) - pose_actual(2), target_pt(1) - pose_actual(1));
                v_cmd = velocidad_dinamica;
                omega_cmd = omega_cmd_pp;

                % Lógicas de Evasión Reactiva
                if strcmp(tipo_controlador, 'Sin Evasión')
                    v_cmd = velocidad_dinamica;
                    omega_cmd = omega_cmd_pp;

                elseif strcmp(tipo_controlador, 'VFH')
                    [rangos, angulos] = sensorLidar(pose_actual, app.MapaOcupacion);
                    direccion_segura = vfh(rangos, angulos, angdiff(pose_actual(3), angulo_objetivo));
                    if isnan(direccion_segura)
                        v_cmd = 0; omega_cmd = 0; acorralado = true; break;
                    else
                        v_cmd = max(1.0, min(v_max * exp(-abs(direccion_segura)), velocidad_dinamica));
                        omega_cmd = max(-omega_max, min(omega_max, 0.8 * direccion_segura));
                    end

                elseif strcmp(tipo_controlador, 'APF')
                    angulos_escaneo = linspace(-pi/3, pi/3, 11) + pose_actual(3);
                    distancias_escaneo = linspace(0.5, 6, 5);
                    fx = 0; fy = 0; peligro_inminente = false;
                    for ang = angulos_escaneo
                        for d = distancias_escaneo
                            test_x = pose_actual(1) + d*cos(ang); test_y = pose_actual(2) + d*sin(ang);
                            if test_x>=limX(1) && test_x<=limX(2) && test_y>=limY(1) && test_y<=limY(2)
                                if getOccupancy(app.MapaOcupacion, [test_x, test_y])
                                    fx = fx - cos(ang)/(d^2); fy = fy - sin(ang)/(d^2);
                                    peligro_inminente = true;
                                end
                            end
                        end
                    end
                    if peligro_inminente
                        dir_neta_x = cos(angulo_objetivo)*1.5 + (fx*8.0);
                        dir_neta_y = sin(angulo_objetivo)*1.5 + (fy*8.0);
                        omega_cmd = max(-omega_max, min(omega_max, 2.0 * angdiff(pose_actual(3), atan2(dir_neta_y, dir_neta_x))));
                        v_cmd = max(1.0, velocidad_dinamica * 0.35);
                    else
                        v_cmd = velocidad_dinamica; omega_cmd = omega_cmd_pp;
                    end

                elseif strcmp(tipo_controlador, 'DWA')
                    candidatos_omega = max(-omega_max, min(omega_max, linspace(-1.5, 1.5, 7) + omega_cmd_pp));
                    costes = zeros(1, 7);
                    for idx = 1:7
                        test_x = pose_actual(1); test_y = pose_actual(2); test_th = pose_actual(3); col_test = false;
                        for h = 1:15
                            test_x = test_x + velocidad_dinamica*cos(test_th)*dt;
                            test_y = test_y + velocidad_dinamica*sin(test_th)*dt;
                            test_th = test_th + candidatos_omega(idx)*dt;
                            if test_x>=limX(1) && test_x<=limX(2) && test_y>=limY(1) && test_y<=limY(2)
                                if getOccupancy(app.MapaOcupacion, [test_x, test_y]), col_test = true; break; end
                            else
                                col_test = true; break;
                            end
                        end
                        costes(idx) = abs(angdiff(test_th, angulo_objetivo)) + (1000 * col_test);
                    end
                    costes(isnan(costes)) = Inf;
                    [c_min, mejor_idx] = min(costes);
                    if c_min >= 1000 || isinf(c_min)
                        v_cmd = 0; omega_cmd = 0; acorralado = true; break;
                    else
                        omega_cmd = candidatos_omega(mejor_idx);
                        if mejor_idx ~= 4, v_cmd = max(1.2, velocidad_dinamica * 0.4); else, v_cmd = velocidad_dinamica; end;
                    end

                elseif strcmp(tipo_controlador, 'FTG')
                    [rangos, angulos] = sensorLidar(pose_actual, app.MapaOcupacion);
                    rangos(isnan(rangos)) = 15;
                    umbral_libre = 1.5;
                    libre = rangos > umbral_libre;
                    if ~any(libre)
                        v_cmd = 0; omega_cmd = 0; acorralado = true; break;
                    else
                        diff_libre = diff([0; libre(:); 0]);
                        inicios = find(diff_libre == 1);
                        fines = find(diff_libre == -1) - 1;
                        [~, idx_max_gap] = max(fines - inicios);
                        idx_centro = round((inicios(idx_max_gap) + fines(idx_max_gap)) / 2);
                        angulo_hueco_central = angulos(idx_centro);
                        peso_meta = 0.3;
                        angulo_final = (1-peso_meta)*angulo_hueco_central + peso_meta*angdiff(pose_actual(3), angulo_objetivo);
                        if isnan(angulo_final), angulo_final = 0; end
                        omega_cmd = max(-omega_max, min(omega_max, 2.0 * angulo_final));
                        v_cmd = max(0.8, velocidad_dinamica * 0.6);
                    end
                end

                % Actualización de la pose 2D
                if isnan(v_cmd), v_cmd = 0; end
                if isnan(omega_cmd), omega_cmd = 0; end
                pose_actual(1) = pose_actual(1) + v_cmd * cos(pose_actual(3)) * dt;
                pose_actual(2) = pose_actual(2) + v_cmd * sin(pose_actual(3)) * dt;
                pose_actual(3) = pose_actual(3) + omega_cmd * dt;

                % Detección de colisiones
                if pose_actual(1)>=limX(1) && pose_actual(1)<=limX(2) && pose_actual(2)>=limY(1) && pose_actual(2)<=limY(2)
                    if getOccupancy(app.MapaOcupacion, pose_actual(1:2)) > 0.5
                        chocado = true;
                        if getOccupancy(app.MapaOcupacionOriginal, pose_actual(1:2)) > 0.5
                            tipo_choque = 'Terreno intransitable';
                            mensaje_choque = '[COLISIÓN] Restricción orográfica excedida.';
                        else
                            tipo_choque = 'Obstáculo Dinámico';
                            mensaje_choque = '[COLISIÓN] Impacto detectado en entorno.';
                        end
                        hold(app.LienzoMapa, 'on');
                        plot(app.LienzoMapa, pose_actual(1), pose_actual(2), 'ro', 'MarkerSize', 20, 'LineWidth', 3, 'Tag', 'Explosion');
                        hold(app.LienzoMapa, 'off');
                        app.ConsoladelSistemaTextArea.Value = [app.ConsoladelSistemaTextArea.Value; {mensaje_choque}];
                        scroll(app.ConsoladelSistemaTextArea, 'bottom');
                        break;
                    end
                else
                    app.ConsoladelSistemaTextArea.Value = [app.ConsoladelSistemaTextArea.Value; {'[ALERTA] Límite de área alcanzado.'}]; break;
                end

                historial = [historial; pose_actual];

                % Proyección Cinemática 3D (Extracción geométrica)
                x_act = pose_actual(1); y_act = pose_actual(2); th_act = pose_actual(3);
                x_FL = x_act + L*cos(th_act) - W*sin(th_act); y_FL = y_act + L*sin(th_act) + W*cos(th_act);
                x_FR = x_act + L*cos(th_act) + W*sin(th_act); y_FR = y_act + L*sin(th_act) - W*cos(th_act);
                x_BL = x_act - L*cos(th_act) - W*sin(th_act); y_BL = y_act - L*sin(th_act) + W*cos(th_act);
                x_BR = x_act - L*cos(th_act) + W*sin(th_act); y_BR = y_act - L*sin(th_act) - W*cos(th_act);

                z_FL = interp2(app.MallaX, app.MallaY, app.RejillaVisual, x_FL, y_FL, 'linear', 0);
                z_FR = interp2(app.MallaX, app.MallaY, app.RejillaVisual, x_FR, y_FR, 'linear', 0);
                z_BL = interp2(app.MallaX, app.MallaY, app.RejillaVisual, x_BL, y_BL, 'linear', 0);
                z_BR = interp2(app.MallaX, app.MallaY, app.RejillaVisual, x_BR, y_BR, 'linear', 0);

                P_FL = [x_FL; y_FL; z_FL]; P_FR = [x_FR; y_FR; z_FR]; P_BL = [x_BL; y_BL; z_BL]; P_BR = [x_BR; y_BR; z_BR];
                V_fwd = (P_FL + P_FR)/2 - (P_BL + P_BR)/2; V_fwd = V_fwd / norm(V_fwd);
                V_left_temp = (P_FL + P_BL)/2 - (P_FR + P_BR)/2;
                V_up = cross(V_fwd, V_left_temp); V_up = V_up / norm(V_up);
                V_left = cross(V_up, V_fwd); V_left = V_left / norm(V_left);

                tform = eye(4); tform(1:3, 1:3) = [V_fwd, V_left, V_up];
                altura_base = mean([z_FL, z_FR, z_BL, z_BR]);
                tform(1:3, 4) = [x_act; y_act; altura_base + offset_z];
                tform_corregida = tform * T_compensacion;

                z_historial = [z_historial; altura_base + 0.2]; %#ok<AGROW>
                v_historial = [v_historial; abs(v_cmd) * 3.6]; %#ok<AGROW>
                pitch_historial = [pitch_historial; rad2deg(asin(V_fwd(3)))]; %#ok<AGROW>

                % Tasa de refresco visual limitada (optimización computacional)
                if mod(paso, 3) == 0
                    app.VelocimetroGauge.Value = abs(v_cmd) * 3.6; app.InclinometroGauge.Value = rad2deg(asin(V_fwd(3)));
                    angulo_yaw_deg = rad2deg(atan2(sin(pose_actual(3)), cos(pose_actual(3))));
                    if isprop(app, 'OrientacionGauge') && ~isempty(app.OrientacionGauge), app.OrientacionGauge.Value = angulo_yaw_deg; end

                    if ~isempty(h_robot2D) && isvalid(h_robot2D), set(h_robot2D, 'XData', [x_FL, x_FR, x_BR, x_BL, x_FL], 'YData', [y_FL, y_FR, y_BR, y_BL, y_FL]); end
                    if ~isempty(h_tray2D) && isvalid(h_tray2D), set(h_tray2D, 'XData', historial(:,1), 'YData', historial(:,2)); end
                    if ~isempty(h_flecha2D) && isvalid(h_flecha2D), set(h_flecha2D, 'XData', x_act, 'YData', y_act, 'UData', cos(th_act)*long_flecha, 'VData', sin(th_act)*long_flecha); end
                    if ~isempty(h_trans3D) && isvalid(h_trans3D), set(h_trans3D, 'Matrix', tform_corregida); end
                    if ~isempty(h_tray3D) && isvalid(h_tray3D), set(h_tray3D, 'XData', historial(:,1), 'YData', historial(:,2), 'ZData', z_historial); end
                    if ~isempty(h_transZoom) && isvalid(h_transZoom), set(h_transZoom, 'Matrix', tform_corregida); end
                    if ~isempty(h_trayZoom) && isvalid(h_trayZoom), set(h_trayZoom, 'XData', historial(:,1), 'YData', historial(:,2), 'ZData', z_historial); end
                    xlim(app.LienzoZoom3D, [x_act - 20, x_act + 20]); ylim(app.LienzoZoom3D, [y_act - 20, y_act + 20]); zlim(app.LienzoZoom3D, [altura_base - 10, altura_base + 15]);

                    if ~isempty(h_transFPV) && isvalid(h_transFPV), set(h_transFPV, 'Matrix', tform_corregida); end
                    mat_cam = tform * [offset_fwd; 0; cam_height; 1]; pos_ojo = mat_cam(1:3)'; pos_objetivo = [x_act + 20*V_fwd(1), y_act + 20*V_fwd(2), altura_base + 5];
                    campos(app.LienzoFPV, pos_ojo); camtarget(app.LienzoFPV, pos_objetivo); camup(app.LienzoFPV, [0 0 1]);
                    drawnow limitrate;
                end

                % Condición de éxito
                if dist_a_meta < 0.5
                    app.ConsoladelSistemaTextArea.Value = [app.ConsoladelSistemaTextArea.Value; {'[ÉXITO] Destino alcanzado.'}]; break;
                end
            end
            app.VelocimetroGauge.Value = 0;

            % =============================================================
            % CÁLCULO DE MÉTRICAS Y REGISTRO TELEMÉTRICO
            % =============================================================

            % Cálculo del Cross-Track Error (Desviación respecto a la ruta global)
            errores_seguimiento = zeros(size(historial, 1), 1);
            for i = 1:size(historial, 1)
                distancias_a_ruta = sqrt((ruta_ideal(:,1) - historial(i,1)).^2 + (ruta_ideal(:,2) - historial(i,2)).^2);
                errores_seguimiento(i) = min(distancias_a_ruta);
            end
            error_medio = mean(errores_seguimiento);
            error_max = max(errores_seguimiento);

            % Mostrar rendimiento en consola
            app.ConsoladelSistemaTextArea.Value = [app.ConsoladelSistemaTextArea.Value; ...
                {sprintf('   > Error Medio (CTE): %.2f m', error_medio)}; ...
                {sprintf('   > Error Máximo: %.2f m', error_max)}; ...
                {'--------------------------------------------------'}];

            app.ContadorMisiones = app.ContadorMisiones + 1;
            motivo = 'Llegada exitosa'; estado_mision_str = 'ÉXITO';
            if app.FlagEStop, estado_mision_str = 'E-STOP'; motivo = 'Parada de emergencia.';
            elseif chocado, estado_mision_str = 'COLISIÓN'; motivo = tipo_choque;
            elseif acorralado, estado_mision_str = 'ACORRALADO'; motivo = 'Bloqueo cinemático.';
            end

            T_sim = (0:size(historial,1)-1)' * dt;
            obs_activos = size(app.RegistroObstaculos, 1);

            % Guardado de datos extendidos (Misión)
            nueva_mision = table(app.ContadorMisiones, {modelo_robot}, {app.PlanificadorGlobalDropDown.Value}, {tipo_controlador}, ...
                app.PoseInicio(1), app.PoseInicio(2), x_meta, y_meta, T_sim(end), obs_activos, {estado_mision_str}, {motivo}, error_medio, error_max, ...
                'VariableNames', {'ID_Mision', 'Robot', 'Planificador_Global', 'Evasion_Local', 'Inicio_X', 'Inicio_Y', 'Meta_X', 'Meta_Y', 'Tiempo_Total_s', 'Num_Obstaculos', 'Estado_Final', 'Motivo_Detencion', 'Error_Medio_m', 'Error_Max_m'});

            if isempty(app.RegistroMisionesGlobal), app.RegistroMisionesGlobal = nueva_mision; else, app.RegistroMisionesGlobal = [app.RegistroMisionesGlobal; nueva_mision]; end

            % Guardado de datos extendidos (Telemetría)
            ID_col = repmat(app.ContadorMisiones, size(historial,1), 1);
            Rob_col = repmat({modelo_robot}, size(historial,1), 1);
            Plan_col = repmat({app.PlanificadorGlobalDropDown.Value}, size(historial,1), 1);
            Cont_col = repmat({tipo_controlador}, size(historial,1), 1);

            nueva_telemetria = table(ID_col, T_sim, historial(:,1), historial(:,2), z_historial, rad2deg(historial(:,3)), v_historial, pitch_historial, Rob_col, Plan_col, Cont_col, ...
                'VariableNames', {'ID_Mision', 'Tiempo_s', 'X_m', 'Y_m', 'Z_m', 'Yaw_deg', 'Velocidad_kmh', 'Pitch_deg', 'Robot', 'Planificador_Global', 'Controlador_Local'});
            if isempty(app.RegistroTelemetriaGlobal), app.RegistroTelemetriaGlobal = nueva_telemetria; else, app.RegistroTelemetriaGlobal = [app.RegistroTelemetriaGlobal; nueva_telemetria]; end

            % =============================================================
            % RE-ANCLAJE PARA CONCATENACIÓN DE MISIONES
            % =============================================================
            app.PoseInicio = pose_actual;
            app.PuntoInicioXY = [pose_actual(1), pose_actual(2)];

            hold(app.LienzoMapa, 'on');
            delete(findobj(app.LienzoMapa, 'Tag', 'Robot2D_Temp'));
            delete(findobj(app.LienzoMapa, 'Tag', 'FlechaInicio_Temp'));
            plot(app.LienzoMapa, pose_actual(1), pose_actual(2), 'gs', 'MarkerSize', 10, 'MarkerFaceColor', 'g', 'Tag', 'Robot2D_Temp');
            quiver(app.LienzoMapa, pose_actual(1), pose_actual(2), cos(pose_actual(3))*10, sin(pose_actual(3))*10, ...
                'Color', 'g', 'LineWidth', 2.5, 'MaxHeadSize', 2, 'AutoScale', 'off', 'Tag', 'FlechaInicio_Temp');
            hold(app.LienzoMapa, 'off');

            app.PuntoMetaXY = []; app.PoseMeta = [];
            app.BotonCalcular.Enable = 'off'; app.BotonSeguir.Enable = 'off';

            app.ConsoladelSistemaTextArea.Value = [app.ConsoladelSistemaTextArea.Value; {'Sistema a la espera de nueva coordenada de destino.'}];
            scroll(app.ConsoladelSistemaTextArea, 'bottom');
        end

        % Button pushed function: BotonEStop
        function BotonEStopButtonPushed(app, event)
            app.FlagEStop = true;
            app.ConsoladelSistemaTextArea.Value = [app.ConsoladelSistemaTextArea.Value; {'[E-STOP] Operación interrumpida.'}];
            scroll(app.ConsoladelSistemaTextArea, 'bottom');
        end

        % Button pushed function: BotonExportar
        function BotonExportarButtonPushed(app, event)
            if isempty(app.RegistroMisionesGlobal)
                app.ConsoladelSistemaTextArea.Value = [app.ConsoladelSistemaTextArea.Value; {'[INFO] Buffer de datos vacío. Ejecute una iteración primero.'}];
            else
                [~, nombre_mapa, ext] = fileparts(app.ArchivoMapaSeleccionado);
                mapa_actual = [nombre_mapa, ext];
                if isempty(nombre_mapa)
                    nombre_mapa = 'Default';
                    mapa_actual = 'No Identificado';
                end

                % 1. Generar el nombre del archivo
                nombre_archivo = sprintf('Reporte_%s_%s.xlsx', nombre_mapa, datestr(now, 'yyyymmdd_HHMMSS'));

                % 2. Construir la ruta dinámica hacia data/Telemetria
                ruta_app = fileparts(mfilename('fullpath'));
                ruta_telemetria = fullfile(ruta_app, '..', 'data', 'Telemetria');

                % 3. Crear la carpeta si no existe (seguridad extra)
                if ~exist(ruta_telemetria, 'dir')
                    mkdir(ruta_telemetria);
                end

                % 4. Unir la ruta de la carpeta con el nombre del Excel
                ruta_completa_exportacion = fullfile(ruta_telemetria, nombre_archivo);

                % 5. Guardar los datos en esa ruta específica
                writetable(app.RegistroMisionesGlobal, ruta_completa_exportacion, 'Sheet', 'Resumen_Misiones');
                writetable(app.RegistroTelemetriaGlobal, ruta_completa_exportacion, 'Sheet', 'Telemetria_Detallada');
                if height(app.RegistroObstaculos) > 0
                    writetable(app.RegistroObstaculos, ruta_completa_exportacion, 'Sheet', 'Obstaculos_Dinámicos');
                end

                % 6. Mostrar el mensaje de éxito en la consola
                app.ConsoladelSistemaTextArea.Value = [app.ConsoladelSistemaTextArea.Value; ...
                    {'Datos exportados correctamente en la carpeta data/Telemetria.'}; {sprintf('Archivo: %s', nombre_archivo)}];
            end
            scroll(app.ConsoladelSistemaTextArea, 'bottom');
        end

        % Button pushed function: SeleccionarInicioButton
        function SeleccionarInicioButtonPushed(app, event)
            app.ConsoladelSistemaTextArea.Value = [app.ConsoladelSistemaTextArea.Value; {'[INPUT] Trace el vector direccional de inicio en el lienzo 2D.'}];
            scroll(app.ConsoladelSistemaTextArea, 'bottom');

            objetos_graficos = findobj(app.LienzoMapa, '-property', 'HitTest');
            set(objetos_graficos, 'HitTest', 'off', 'PickableParts', 'none');

            linea_roi = drawline(app.LienzoMapa, 'Color', 'g', 'LineWidth', 2);
            pos = linea_roi.Position;

            x_ini = pos(1,1); y_ini = pos(1,2);
            dx = pos(2,1) - pos(1,1); dy = pos(2,2) - pos(1,2);
            if norm([dx, dy]) < 0.1, theta_rad = 0; else, theta_rad = atan2(dy, dx); end

            app.PuntoInicioXY = [x_ini, y_ini];
            app.PoseInicio = [x_ini, y_ini, theta_rad];

            hold(app.LienzoMapa, 'on');
            delete(findobj(app.LienzoMapa, 'Tag', 'Robot2D_Temp')); delete(findobj(app.LienzoMapa, 'Tag', 'FlechaInicio_Temp'));
            plot(app.LienzoMapa, x_ini, y_ini, 'gs', 'MarkerSize', 10, 'MarkerFaceColor', 'g', 'Tag', 'Robot2D_Temp', 'HitTest', 'off');
            quiver(app.LienzoMapa, x_ini, y_ini, cos(theta_rad)*10, sin(theta_rad)*10, 'Color', 'g', 'LineWidth', 2.5, 'MaxHeadSize', 2, 'AutoScale', 'off', 'Tag', 'FlechaInicio_Temp', 'HitTest', 'off');
            hold(app.LienzoMapa, 'off');
            delete(linea_roi);

            app.ConsoladelSistemaTextArea.Value = [app.ConsoladelSistemaTextArea.Value; {sprintf('Origen configurado (X:%.1f, Y:%.1f, Th:%.1fº)', x_ini, y_ini, rad2deg(theta_rad))}];
            scroll(app.ConsoladelSistemaTextArea, 'bottom');
        end

        % Button pushed function: SeleccionarMetaButton
        function SeleccionarMetaButtonPushed(app, event)
            app.ConsoladelSistemaTextArea.Value = [app.ConsoladelSistemaTextArea.Value; {'[INPUT] Trace el vector direccional de meta en el lienzo 2D.'}];
            scroll(app.ConsoladelSistemaTextArea, 'bottom');

            objetos_graficos = findobj(app.LienzoMapa, '-property', 'HitTest');
            set(objetos_graficos, 'HitTest', 'off', 'PickableParts', 'none');

            linea_roi = drawline(app.LienzoMapa, 'Color', 'b', 'LineWidth', 2);
            pos = linea_roi.Position;

            x_meta = pos(1,1); y_meta = pos(1,2);
            dx = pos(2,1) - pos(1,1); dy = pos(2,2) - pos(1,2);
            if norm([dx, dy]) < 0.1, theta_rad = 0; else, theta_rad = atan2(dy, dx); end

            app.PuntoMetaXY = [x_meta, y_meta];
            app.PoseMeta = [x_meta, y_meta, theta_rad];

            hold(app.LienzoMapa, 'on');
            delete(findobj(app.LienzoMapa, 'Tag', 'Meta2D_Temp')); delete(findobj(app.LienzoMapa, 'Tag', 'FlechaMeta_Temp'));
            plot(app.LienzoMapa, x_meta, y_meta, 'bo', 'MarkerSize', 10, 'MarkerFaceColor', 'b', 'Tag', 'Meta2D_Temp', 'HitTest', 'off');
            quiver(app.LienzoMapa, x_meta, y_meta, cos(theta_rad)*10, sin(theta_rad)*10, 'Color', 'b', 'LineWidth', 2.5, 'MaxHeadSize', 2, 'AutoScale', 'off', 'Tag', 'FlechaMeta_Temp', 'HitTest', 'off');
            hold(app.LienzoMapa, 'off');
            delete(linea_roi);

            app.ConsoladelSistemaTextArea.Value = [app.ConsoladelSistemaTextArea.Value; {sprintf('Meta configurada (X:%.1f, Y:%.1f, Th:%.1fº)', x_meta, y_meta, rad2deg(theta_rad))}];
            scroll(app.ConsoladelSistemaTextArea, 'bottom');
        end

        % Button pushed function: ReiniciarButton
        function ReiniciarButtonPushed(app, event)
            cla(app.LienzoMapa);
            cla(app.RelieveMapa);
            cla(app.LienzoZoom3D);
            cla(app.LienzoFPV);

            app.SeleccionarInicioButton.Enable = 'off';
            app.SeleccionarMetaButton.Enable = 'off';
            app.BotonPosicionar.Enable = 'off';
            app.BotonCalcular.Enable = 'off';
            app.BotonSeguir.Enable = 'off';

            app.VelocimetroGauge.Value = 0;
            app.InclinometroGauge.Value = 0;

            if isprop(app, 'OrientacionGauge') && ~isempty(app.OrientacionGauge)
                app.OrientacionGauge.Value = 0;
            end

            app.ArchivoMapaSeleccionado = '';
            app.MapaOcupacion = [];
            app.MapaOcupacionOriginal = [];
            app.RejillaVisual = [];
            app.ImagenTextura = [];
            app.MallaX = [];
            app.MallaY = [];

            app.RutaGlobal = [];
            app.PuntoInicioXY = [];
            app.PuntoMetaXY = [];
            app.PoseInicio = [];
            app.PoseMeta = [];

            app.ContadorMisiones = 0;
            app.RegistroTelemetriaGlobal = table();
            app.RegistroMisionesGlobal = table();
            app.RegistroObstaculos = table('Size', [0 3], 'VariableTypes', {'string', 'double', 'double'}, 'VariableNames', {'Forma', 'Centro_X', 'Centro_Y'});

            app.ConsoladelSistemaTextArea.Value = {'Memoria purgada. Requiere nueva inicialización de entorno.'};
            scroll(app.ConsoladelSistemaTextArea, 'bottom');
        end

        % Button pushed function: BotonAnadirObstaculo
        function BotonAnadirObstaculoButtonPushed(app, event)
            forma = app.FormaObstaculoDropDown.Value;
            app.ConsoladelSistemaTextArea.Value = [app.ConsoladelSistemaTextArea.Value; {['Defina el área (', forma, ') sobre el costmap 2D.']}];
            scroll(app.ConsoladelSistemaTextArea, 'bottom');

            objetos_graficos = findobj(app.LienzoMapa, '-property', 'HitTest'); set(objetos_graficos, 'HitTest', 'off', 'PickableParts', 'none');

            if strcmp(forma, 'Círculo'), roi = drawcircle(app.LienzoMapa, 'Color', 'k', 'FaceAlpha', 0.6, 'LineWidth', 2);
            else, roi = drawrectangle(app.LienzoMapa, 'Color', 'k', 'FaceAlpha', 0.6, 'LineWidth', 2); end

            mascara_obstaculo = inROI(roi, app.MallaX, app.MallaY);
            X_obs = app.MallaX(mascara_obstaculo); Y_obs = app.MallaY(mascara_obstaculo);
            if isempty(X_obs), delete(roi); return; end

            nueva_roca = table({forma}, mean(X_obs), mean(Y_obs), 'VariableNames', {'Forma', 'Centro_X', 'Centro_Y'});
            app.RegistroObstaculos = [app.RegistroObstaculos; nueva_roca];

            setOccupancy(app.MapaOcupacion, [X_obs, Y_obs], 1);

            Z_obs = interp2(app.MallaX, app.MallaY, app.RejillaVisual, X_obs, Y_obs, 'linear');
            altura_roca = 2.0;

            hold(app.RelieveMapa, 'on'); plot3(app.RelieveMapa, X_obs, Y_obs, Z_obs + altura_roca, 'k.', 'Tag', 'ObstaculoCustom'); hold(app.RelieveMapa, 'off');
            hold(app.LienzoZoom3D, 'on'); plot3(app.LienzoZoom3D, X_obs, Y_obs, Z_obs + altura_roca, 'k.', 'Tag', 'ObstaculoCustom'); hold(app.LienzoZoom3D, 'off');
            hold(app.LienzoFPV, 'on'); plot3(app.LienzoFPV, X_obs, Y_obs, Z_obs + altura_roca, 'k.', 'Tag', 'ObstaculoCustom'); hold(app.LienzoFPV, 'off');

            roi.InteractionsAllowed = 'none';
            app.ConsoladelSistemaTextArea.Value = [app.ConsoladelSistemaTextArea.Value; {'Costmap local actualizado.'}];
            scroll(app.ConsoladelSistemaTextArea, 'bottom');
        end

        % Button pushed function: BotonLimpiarObstaculos
        function BotonLimpiarObstaculosButtonPushed(app, event)
            rois_circulos = findobj(app.LienzoMapa, 'Type', 'images.roi.Circle');
            rois_rectangulos = findobj(app.LienzoMapa, 'Type', 'images.roi.Rectangle');
            delete(rois_circulos);
            delete(rois_rectangulos);

            delete(findobj(app.LienzoMapa, 'Tag', 'ObstaculoCustom'));
            delete(findobj(app.RelieveMapa, 'Tag', 'ObstaculoCustom'));
            delete(findobj(app.LienzoZoom3D, 'Tag', 'ObstaculoCustom'));
            delete(findobj(app.LienzoFPV, 'Tag', 'ObstaculoCustom'));

            app.MapaOcupacion = copy(app.MapaOcupacionOriginal);

            app.ConsoladelSistemaTextArea.Value = [app.ConsoladelSistemaTextArea.Value; {'Restauración del entorno completada.'}];
            scroll(app.ConsoladelSistemaTextArea, 'bottom');
        end

        % Button pushed function: SeleccionarMapaButton
        function SeleccionarMapaButtonPushed(app, event)
            [file, path] = uigetfile('*.mat', 'Seleccione el Modelo de Elevación Digital');
            if isequal(file, 0)
                app.ConsoladelSistemaTextArea.Value = [app.ConsoladelSistemaTextArea.Value; {'[INFO] Selección de mapa cancelada.'}];
                scroll(app.ConsoladelSistemaTextArea, 'bottom');
                return;
            end

            app.ArchivoMapaSeleccionado = fullfile(path, file);
            app.ConsoladelSistemaTextArea.Value = [app.ConsoladelSistemaTextArea.Value; ...
                {['Archivo seleccionado: ', file]}; {'Pulse "Cargar Mapa" para procesar la topografía.'}];
            scroll(app.ConsoladelSistemaTextArea, 'bottom');
        end

        % Button pushed function: CargarMapaButton
        function CargarMapaButtonPushed(app, event)
            if isempty(app.ArchivoMapaSeleccionado)
                uialert(app.UIFigure, 'Seleccione un archivo .mat primero.', 'Aviso');
                return;
            end

            app.ConsoladelSistemaTextArea.Value = [app.ConsoladelSistemaTextArea.Value; {'Procesando topografía y calculando costmaps...'}];
            drawnow;

            try
                datos = load(app.ArchivoMapaSeleccionado);

                % Extracción de la matriz de elevación
                if isfield(datos, 'rejilla')
                    rejilla_raw = datos.rejilla;
                elseif isfield(datos, 'Z')
                    rejilla_raw = datos.Z;
                else
                    nombres = fieldnames(datos);
                    rejilla_raw = datos.(nombres{1});
                end

                tiene_imagen = isfield(datos, 'imagen');
                if tiene_imagen
                    imagen_raw = datos.imagen;
                end

                % Adaptación geométrica según origen del DEM
                if contains(app.ArchivoMapaSeleccionado, '2023')
                    % Corrección para el mapa base
                    rejilla_final = rot90(flipud(rejilla_raw), -1);
                    if tiene_imagen
                        imagen_final = flipud(imagen_raw);
                    end
                else
                    % Transposición requerida para mapas pre/post
                    rejilla_final = rejilla_raw;
                    if tiene_imagen
                        imagen_final = flipud(imagen_raw);
                    end
                end

                mascara_final = isnan(rejilla_final) | (rejilla_final <= -999) | (rejilla_final <= 0);

                % Suavizado de la malla (filtro mediano + gaussiano)
                rejilla_filt = medfilt2(rejilla_final, [7 7]);
                rejilla_suavizada = imgaussfilt(rejilla_filt, 1);
                rejilla_suavizada(mascara_final) = NaN;

                % Normalización de textura si existe
                if tiene_imagen
                    if max(imagen_final(:)) > 1
                        app.ImagenTextura = double(imagen_final) / 255.0;
                    else
                        app.ImagenTextura = double(imagen_final);
                    end

                    for k = 1:3
                        canal = app.ImagenTextura(:,:,k);
                        canal(mascara_final) = 1.0;
                        app.ImagenTextura(:,:,k) = canal;
                    end
                else
                    app.ImagenTextura = [];
                end

                % Rejilla espacial indexada
                [app.MallaX, app.MallaY] = meshgrid(1:size(rejilla_suavizada,2), 1:size(rejilla_suavizada,1));

                % Descriptores 2.5D: Pendiente y Rugosidad
                [Gx, Gy] = gradient(rejilla_suavizada);
                pendiente_grados = rad2deg(atan(sqrt(Gx.^2 + Gy.^2)));
                rugosidad_suelo = stdfilt(rejilla_suavizada, true(5));

                % Binarización de obstáculos (Inclinación > 15º o Rugosidad > 0.3)
                matriz_obstaculos = (pendiente_grados > 15) | (rugosidad_suelo > 0.3) | mascara_final;

                mapa = binaryOccupancyMap(flipud(matriz_obstaculos), 1);
                inflate(mapa, 1.5); % Margen de seguridad del chasis

                app.MapaOcupacion = mapa;
                app.RejillaVisual = rejilla_suavizada;
                app.MapaOcupacionOriginal = copy(mapa);

                % Reinicio de Data Logger
                app.ContadorMisiones = 0;
                app.RegistroTelemetriaGlobal = table();
                app.RegistroMisionesGlobal = table();
                app.RegistroObstaculos = table('Size', [0 3], 'VariableTypes', {'string', 'double', 'double'}, 'VariableNames', {'Forma', 'Centro_X', 'Centro_Y'});

                % Limpieza de axes
                cla(app.LienzoMapa); cla(app.RelieveMapa); cla(app.LienzoZoom3D); cla(app.LienzoFPV);

                % Renderizado 2D
                if ~isempty(app.ImagenTextura)
                    image(app.LienzoMapa, 'XData', [1 size(app.ImagenTextura,2)], 'YData', [1 size(app.ImagenTextura,1)], 'CData', app.ImagenTextura);
                    set(app.LienzoMapa, 'YDir', 'normal');
                    title(app.LienzoMapa, 'Vista 2D (Ortofoto)');
                else
                    show(app.MapaOcupacion, 'Parent', app.LienzoMapa);
                    title(app.LienzoMapa, 'Mapa de Ocupación Binario');
                end
                axis(app.LienzoMapa, 'tight'); axis(app.LienzoMapa, 'equal');
                xlabel(app.LienzoMapa, 'X (m)'); ylabel(app.LienzoMapa, 'Y (m)');

                % Renderizado 3D (Texturizado vs Sólido)
                if ~isempty(app.ImagenTextura)
                    h_surf = surf(app.RelieveMapa, app.MallaX, app.MallaY, app.RejillaVisual, ...
                        'EdgeColor', 'none', 'FaceColor', 'texturemap', 'CData', app.ImagenTextura);
                    daspect(app.RelieveMapa, [1 1 1]);
                else
                    h_surf = surf(app.RelieveMapa, app.MallaX, app.MallaY, app.RejillaVisual, 'EdgeColor', 'none');
                    colormap(app.RelieveMapa, 'turbo'); % Paleta nativa segura
                    daspect(app.RelieveMapa, [1 1 0.5]);
                end

                % Ajuste de iluminación
                h_surf.AmbientStrength = 0.45;
                h_surf.DiffuseStrength = 0.65;
                h_surf.SpecularStrength = 0.05;
                delete(findall(app.RelieveMapa, 'Type', 'light'));
                light(app.RelieveMapa, 'Position', [-1 -1 1], 'Style', 'infinite');
                lighting(app.RelieveMapa, 'gouraud');

                view(app.RelieveMapa, -35, 45);
                grid(app.RelieveMapa, 'on'); axis(app.RelieveMapa, 'tight');
                title(app.RelieveMapa, 'Modelo Digital de Elevación (3D)');
                xlabel(app.RelieveMapa, 'X (m)'); ylabel(app.RelieveMapa, 'Y (m)'); zlabel(app.RelieveMapa, 'Z (m)');

                % Habilitar controles
                app.SeleccionarInicioButton.Enable = 'on';
                app.SeleccionarMetaButton.Enable = 'on';
                app.BotonPosicionar.Enable = 'on';

                app.ConsoladelSistemaTextArea.Value = [app.ConsoladelSistemaTextArea.Value; ...
                    {''}; ...
                    {'[SISTEMA] Carga completada. Listo para planificación.'}];
                scroll(app.ConsoladelSistemaTextArea, 'bottom');

            catch ME
                app.ConsoladelSistemaTextArea.Value = [app.ConsoladelSistemaTextArea.Value; {'[ERROR] Fallo al cargar el entorno:'}; {ME.message}];
                scroll(app.ConsoladelSistemaTextArea, 'bottom');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.PaneldeControl = uipanel(app.GridLayout);
            app.PaneldeControl.Title = 'Panel de Control';
            app.PosicionesInicialyFinalLabel = uilabel(app.PaneldeControl);
            app.PosicionesInicialyFinalLabel.Text = 'Posiciones Inicial y Final';
            app.SeleccionarInicioButton = uibutton(app.PaneldeControl, 'push');
            app.SeleccionarInicioButton.ButtonPushedFcn = createCallbackFcn(app, @SeleccionarInicioButtonPushed, true);
            app.SeleccionarInicioButton.Text = 'Seleccionar Inicio';
            app.SeleccionarMetaButton = uibutton(app.PaneldeControl, 'push');
            app.SeleccionarMetaButton.ButtonPushedFcn = createCallbackFcn(app, @SeleccionarMetaButtonPushed, true);
            app.SeleccionarMetaButton.Text = 'Seleccionar Meta';
            app.ModelodelRobotDropDownLabel = uilabel(app.PaneldeControl);
            app.ModelodelRobotDropDownLabel.Text = 'Modelo del Robot';
            app.ModelodelRobotDropDown = uidropdown(app.PaneldeControl);
            app.ModelodelRobotDropDown.Items = {'Argo J8', 'Rambler'};
            app.ModelodelRobotDropDown.Value = 'Argo J8';
            app.BotonPosicionar = uibutton(app.PaneldeControl, 'push');
            app.BotonPosicionar.ButtonPushedFcn = createCallbackFcn(app, @BotonPosicionarButtonPushed, true);
            app.BotonPosicionar.Text = 'Posicionar Robot';
            app.PlanificadorGlobalDropDownLabel = uilabel(app.PaneldeControl);
            app.PlanificadorGlobalDropDownLabel.Text = 'Planificador Global';
            app.PlanificadorGlobalDropDown = uidropdown(app.PaneldeControl);
            app.PlanificadorGlobalDropDown.Items = {'PRM', 'RRT*', 'A Grid*', 'Hybrid A*'};
            app.PlanificadorGlobalDropDown.Value = 'PRM';
            app.BotonCalcular = uibutton(app.PaneldeControl, 'push');
            app.BotonCalcular.ButtonPushedFcn = createCallbackFcn(app, @BotonCalcularButtonPushed, true);
            app.BotonCalcular.Enable = 'off';
            app.BotonCalcular.Text = 'Calcular Ruta';
            app.FormadelObstculoDropDownLabel = uilabel(app.PaneldeControl);
            app.FormadelObstculoDropDownLabel.Text = 'Forma del Obstáculo';
            app.FormaObstaculoDropDown = uidropdown(app.PaneldeControl);
            app.FormaObstaculoDropDown.Items = {'Círculo', 'Rectángulo'};
            app.FormaObstaculoDropDown.Value = 'Círculo';
            app.BotonAnadirObstaculo = uibutton(app.PaneldeControl, 'push');
            app.BotonAnadirObstaculo.ButtonPushedFcn = createCallbackFcn(app, @BotonAnadirObstaculoButtonPushed, true);
            app.BotonAnadirObstaculo.Text = 'Añadir Obstáculo';
            app.BotonLimpiarObstaculos = uibutton(app.PaneldeControl, 'push');
            app.BotonLimpiarObstaculos.ButtonPushedFcn = createCallbackFcn(app, @BotonLimpiarObstaculosButtonPushed, true);
            app.BotonLimpiarObstaculos.Text = 'Limpiar Obstáculos';
            app.BotonSeguir = uibutton(app.PaneldeControl, 'push');
            app.BotonSeguir.ButtonPushedFcn = createCallbackFcn(app, @BotonSeguirButtonPushed, true);
            app.BotonSeguir.Enable = 'off';
            app.BotonSeguir.Text = 'Seguir Trayectoria';
            app.ReiniciarButton = uibutton(app.PaneldeControl, 'push');
            app.ReiniciarButton.ButtonPushedFcn = createCallbackFcn(app, @ReiniciarButtonPushed, true);
            app.ReiniciarButton.Text = 'Reiniciar';
            app.BotonEStop = uibutton(app.PaneldeControl, 'push');
            app.BotonEStop.ButtonPushedFcn = createCallbackFcn(app, @BotonEStopButtonPushed, true);
            app.BotonEStop.Text = 'E-Stop';
            app.BotonExportar = uibutton(app.PaneldeControl, 'push');
            app.BotonExportar.ButtonPushedFcn = createCallbackFcn(app, @BotonExportarButtonPushed, true);
            app.BotonExportar.Text = 'Exportar Datos';
            app.ConsoladelSistemaTextAreaLabel = uilabel(app.PaneldeControl);
            app.ConsoladelSistemaTextAreaLabel.Text = 'Consola del Sistema';
            app.ConsoladelSistemaTextArea = uitextarea(app.PaneldeControl);
            app.ConsoladelSistemaTextArea.Editable = 'off';
            app.EvasinDinmicaLabel = uilabel(app.PaneldeControl);
            app.EvasinDinmicaLabel.Text = 'Evasión Dinámica';
            app.ControladorLocalDropDown = uidropdown(app.PaneldeControl);
            app.ControladorLocalDropDown.Items = {'Sin Evasión', 'VFH', 'APF', 'DWA', 'FTG'};
            app.ControladorLocalDropDown.Value = 'Sin Evasión';
            app.SeleccionarMapaButton = uibutton(app.PaneldeControl, 'push');
            app.SeleccionarMapaButton.ButtonPushedFcn = createCallbackFcn(app, @SeleccionarMapaButtonPushed, true);
            app.SeleccionarMapaButton.Text = 'Seleccionar Mapa';
            app.CargarMapaButton = uibutton(app.PaneldeControl, 'push');
            app.CargarMapaButton.ButtonPushedFcn = createCallbackFcn(app, @CargarMapaButtonPushed, true);
            app.CargarMapaButton.Text = 'Cargar Mapa';
            app.Label = uilabel(app.PaneldeControl);
            app.Label.Text = '___________________________________________';
            app.Label_2 = uilabel(app.PaneldeControl);
            app.Label_2.Text = '___________________________________________';
            app.Label_3 = uilabel(app.PaneldeControl);
            app.Label_3.Text = '___________________________________________';
            app.Label_4 = uilabel(app.PaneldeControl);
            app.Label_4.Text = '___________________________________________';
            app.Visualizacion2D3DPanel = uipanel(app.GridLayout);
            app.Visualizacion2D3DPanel.Title = 'Panel de Visualización';
            app.TabGroup = uitabgroup(app.Visualizacion2D3DPanel);
            app.Relieve3DTab = uitab(app.TabGroup);
            app.Relieve3DTab.Title = 'Relieve (3D)';
            app.GridLayout4 = uigridlayout(app.Relieve3DTab);
            app.RelieveMapa = uiaxes(app.GridLayout4);
            xlabel(app.RelieveMapa, 'X')
            ylabel(app.RelieveMapa, 'Y')
            zlabel(app.RelieveMapa, 'Z')
            app.Planta2DTab = uitab(app.TabGroup);
            app.Planta2DTab.Title = 'Planta (2D)';
            app.GridLayout2 = uigridlayout(app.Planta2DTab);
            app.LienzoMapa = uiaxes(app.GridLayout2);
            xlabel(app.LienzoMapa, 'X')
            ylabel(app.LienzoMapa, 'Y')
            zlabel(app.LienzoMapa, 'Z')
            app.ZoomRelieveTab = uitab(app.TabGroup);
            app.ZoomRelieveTab.Title = 'Zoom Relieve';
            app.GridLayout3 = uigridlayout(app.ZoomRelieveTab);
            app.LienzoZoom3D = uiaxes(app.GridLayout3);
            xlabel(app.LienzoZoom3D, 'X')
            ylabel(app.LienzoZoom3D, 'Y')
            zlabel(app.LienzoZoom3D, 'Z')
            app.CamaraBordoFPVTab = uitab(app.TabGroup);
            app.CamaraBordoFPVTab.Title = 'Cámara a Bordo (FPV)';
            app.GridLayout5 = uigridlayout(app.CamaraBordoFPVTab);
            app.LienzoFPV = uiaxes(app.GridLayout5);
            app.VelocimetroGauge = uigauge(app.GridLayout5, 'semicircular');
            app.VelocimetroGauge.Limits = [0 35];
            app.VelocidadkmhLabel = uilabel(app.GridLayout5);
            app.VelocidadkmhLabel.Text = 'Velocidad (km/h)';
            app.InclinometroGauge = uigauge(app.GridLayout5, 'semicircular');
            app.InclinometroGauge.Limits = [-45 45];
            app.CabeceoGaugeLabel = uilabel(app.GridLayout5);
            app.CabeceoGaugeLabel.Text = 'Cabeceo (º)';
            app.OrientacionGauge = uigauge(app.GridLayout5, 'circular');
            app.OrientacionGauge.Limits = [-180 180];
            app.OrientacinLabel = uilabel(app.GridLayout5);
            app.OrientacinLabel.Text = {'Orientación'; '(Yaw º)'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TFM_ArgoJ8_Dashboard

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end